classdef ScoreObjectiveDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WildlifeScoringObjectivesUIFigure  matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, scoringRules)
            app.MainApp = mainApp;

            % Set location same as parent dialog
            app.WildlifeScoringObjectivesUIFigure.Position(1:2) = mainApp.CascadiaUIFigure.Position(1:2);
        end

        % Close request function: WildlifeScoringObjectivesUIFigure
        function WildlifeScoringObjectivesUIFigureCloseRequest(app, event)
            % Re-enable main app
            if isvalid(app.MainApp)
                app.MainApp.ScoringObjectivesButton.Enable = true;
                app.MainApp.NewGameButton.Enable = true;
                setGameOptionsEnableStatus(app.MainApp, true);
                setGameInteractivity(app.MainApp, true);
            end

            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WildlifeScoringObjectivesUIFigure = uifigure('Visible', 'off');
            app.WildlifeScoringObjectivesUIFigure.Name = 'Wildlife Scoring Objectives';
            app.WildlifeScoringObjectivesUIFigure.Icon = fullfile(pathToMLAPP, 'HexIcon.png');
            app.WildlifeScoringObjectivesUIFigure.CloseRequestFcn = createCallbackFcn(app, @WildlifeScoringObjectivesUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.WildlifeScoringObjectivesUIFigure);
            % Show the figure after all components are created
            app.WildlifeScoringObjectivesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreObjectiveDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WildlifeScoringObjectivesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.WildlifeScoringObjectivesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WildlifeScoringObjectivesUIFigure)
        end
    end
end